classdef Section2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        IesuAgapito2216065ProfClaydonLabel  matlab.ui.control.Label
        EnterSurfaceAreaToBeginLabel    matlab.ui.control.Label
        OutputsLabel                    matlab.ui.control.Label
        BaseorRadiusLengthinEditField   matlab.ui.control.NumericEditField
        BaseorRadiusLengthinEditFieldLabel  matlab.ui.control.Label
        InputsLabel                     matlab.ui.control.Label
        EnterSurfaceAreaAboveLamp       matlab.ui.control.Lamp
        CylinderVolumexradiusinLamp     matlab.ui.control.Lamp
        CylinderVolumexradiusinLampLabel  matlab.ui.control.Label
        ShapeofGreatestVolumeLabel      matlab.ui.control.Label
        SquareVolumexbaselengthinLamp   matlab.ui.control.Lamp
        BestOptionEditField             matlab.ui.control.EditField
        BestOptionEditFieldLabel        matlab.ui.control.Label
        OpenorClosedTopDropDown         matlab.ui.control.DropDown
        OpenorClosedTopLabel            matlab.ui.control.Label
        MaximumVolumein3EditField       matlab.ui.control.NumericEditField
        MaximumVolumein3EditFieldLabel  matlab.ui.control.Label
        CylinderVolumein3EditField      matlab.ui.control.NumericEditField
        CylinderVolumein3Label          matlab.ui.control.Label
        SquarePrismVolumein3EditField   matlab.ui.control.NumericEditField
        SquarePrismVolumein3EditFieldLabel  matlab.ui.control.Label
        SurfaceAreain2EditField         matlab.ui.control.NumericEditField
        SurfaceAreain2Label             matlab.ui.control.Label
        VolumeOptimizationLabel         matlab.ui.control.Label
        CALCULATEButton                 matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CALCULATEButton
        function CALCULATEButtonPushed(app, event)
            %Iesu Agapito
            %Prof. Claydon
            %2216065
            %10/28/22
   
            %Define the Problem: Optimize the volume of a container given
            %surface area, a  closed or open top, and choices between a
            %square prism and cylinder container.
            %Collect Data: Inputs
            app.EnterSurfaceAreaToBeginLabel.Text = 'Thinking...';
            SA = app.SurfaceAreain2EditField.Value;
            if SA <= 0
                app.EnterSurfaceAreaToBeginLabel.Text = 'Invalid Value!';
                pause(3);
                app.EnterSurfaceAreaToBeginLabel.Text = 'Enter Surface Area';
                return
            end
            TopCondition = app.OpenorClosedTopDropDown.Value;
            switch TopCondition
                case 'Closed'
                    BaseCount = 2;
                case 'Open'
                    BaseCount = 1;
            end
            %Generate Solutions: Using volume formulas of both shapes in terms
            % of base length/radius, accounting for lid condition, create
            % vectors of possible length/radius values and evaluate
            % maximum.


            %Refine and Implement 
            %Calculations:
            x = [0:(SA./10000):SA.*100];
            Square_V = (SA.*x.^2-BaseCount.*x.^4)./(4.*x);
            Cylinder_V = (SA.*x-BaseCount.*pi.*x.^3)./2;
            
            [maxSqV, index] = max(Square_V);
            x_maxSqV = x(index);
            [maxCyV,  index2] = max(Cylinder_V);
            x_maxCyV = x(index2);

            %Outputs
            app.BestOptionEditField.BackgroundColor  =  [0, 1, 0];
            app.MaximumVolumein3EditField.BackgroundColor = [0, 1, 0];
            app.BaseorRadiusLengthinEditField.BackgroundColor = [0, 1, 0];
            app.EnterSurfaceAreaToBeginLabel.Text = 'Done!';
            app.EnterSurfaceAreaAboveLamp.Color = [0, 1, 0];
            app.SquarePrismVolumein3EditField.Value = maxSqV;
            app.CylinderVolumein3EditField.Value = maxCyV;
            if maxSqV > maxCyV
                app.BestOptionEditField.Value = 'Square Prism';
                app.MaximumVolumein3EditField.Value = maxSqV;
                app.BaseorRadiusLengthinEditField.Value = x_maxSqV;
            elseif maxCyV > maxSqV
                app.BestOptionEditField.Value = 'Cylinder';
                app.MaximumVolumein3EditField.Value = maxCyV;
                app.BaseorRadiusLengthinEditField.Value = x_maxCyV;
            end

            %Verify and Test: Plotting
            %Can also refer to handsolution to check if MATLAB-calculated value matches
            %hand-calculated value.
            cla(app.UIAxes);
            plot(app.UIAxes,x, Square_V,'b');
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, x_maxSqV, max(Square_V),'*');
            hold(app.UIAxes, 'on');
            plot(app.UIAxes,x, Cylinder_V, 'r');
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, x_maxCyV, max(Cylinder_V), '*');
            axis(app.UIAxes, [0, x_maxSqV.*2, 0, maxCyV.*2]);

            %Visualization Extra
            cla(app.UIAxes2);
            if maxCyV > maxSqV
                h = (SA-pi*x_maxCyV.^2)./(2.*pi.*x_maxCyV);
                [X, Y ,Z] = cylinder(x_maxCyV);
                Z = Z.*h;
                surf(app.UIAxes2,X,Y,Z);
            elseif maxSqV > maxCyV
                h = (SA-2.*x_maxSqV.^2)./(4.*x_maxSqV);
                a = x_maxSqV;
                X = [a,a,a,a,a,a,a,a];
                Y = [a,a,a,a,a,a,a,a];
                Z = [0,0,0,0,h,h,h,h];
                surf(app.UIAxes2,X,Y,Z);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Volume vs Dimension')
            xlabel(app.UIAxes, 'X [in.]')
            ylabel(app.UIAxes, 'Volume [in^3]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Container Visualization')
            xlabel(app.UIAxes2, 'Base')
            ylabel(app.UIAxes2, 'Base')
            zlabel(app.UIAxes2, 'Height')
            app.CALCULATEButton = uibutton(app.UIFigure, 'push');
            app.CALCULATEButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULATEButtonPushed, true);
            app.CALCULATEButton.Text = 'CALCULATE!';
            app.VolumeOptimizationLabel = uilabel(app.UIFigure);
            app.VolumeOptimizationLabel.Text = 'Volume Optimization';
            app.SurfaceAreain2Label = uilabel(app.UIFigure);
            app.SurfaceAreain2Label.Text = 'Surface Area [in^2]';
            app.SurfaceAreain2EditField = uieditfield(app.UIFigure, 'numeric');
            app.SquarePrismVolumein3EditFieldLabel = uilabel(app.UIFigure);
            app.SquarePrismVolumein3EditFieldLabel.Text = 'Square Prism Volume [in^3] ';
            app.SquarePrismVolumein3EditField = uieditfield(app.UIFigure, 'numeric');
            app.SquarePrismVolumein3EditField.Editable = 'off';
            app.CylinderVolumein3Label = uilabel(app.UIFigure);
            app.CylinderVolumein3Label.Text = 'Cylinder Volume [in^3]';
            app.CylinderVolumein3EditField = uieditfield(app.UIFigure, 'numeric');
            app.CylinderVolumein3EditField.Editable = 'off';
            app.MaximumVolumein3EditFieldLabel = uilabel(app.UIFigure);
            app.MaximumVolumein3EditFieldLabel.Text = 'Maximum Volume [in^3]';
            app.MaximumVolumein3EditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumVolumein3EditField.Editable = 'off';
            app.OpenorClosedTopLabel = uilabel(app.UIFigure);
            app.OpenorClosedTopLabel.Text = {'Open or Closed'; 'Top?'};
            app.OpenorClosedTopDropDown = uidropdown(app.UIFigure);
            app.OpenorClosedTopDropDown.Items = {'Closed', 'Open'};
            app.OpenorClosedTopDropDown.Value = 'Closed';
            app.BestOptionEditFieldLabel = uilabel(app.UIFigure);
            app.BestOptionEditFieldLabel.Text = 'Best Option';
            app.BestOptionEditField = uieditfield(app.UIFigure, 'text');
            app.BestOptionEditField.Editable = 'off';
            app.SquareVolumexbaselengthinLamp = uilamp(app.UIFigure);
            app.ShapeofGreatestVolumeLabel = uilabel(app.UIFigure);
            app.ShapeofGreatestVolumeLabel.Text = {'Square Volume'; '(x = base length [in.])'};
            app.CylinderVolumexradiusinLampLabel = uilabel(app.UIFigure);
            app.CylinderVolumexradiusinLampLabel.Text = {'Cylinder Volume'; '(x = radius [in.])'};
            app.CylinderVolumexradiusinLamp = uilamp(app.UIFigure);
            app.EnterSurfaceAreaAboveLamp = uilamp(app.UIFigure);
            app.InputsLabel = uilabel(app.UIFigure);
            app.InputsLabel.Text = 'Inputs';
            app.BaseorRadiusLengthinEditFieldLabel = uilabel(app.UIFigure);
            app.BaseorRadiusLengthinEditFieldLabel.Text = 'Base or Radius Length [in]';
            app.BaseorRadiusLengthinEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaseorRadiusLengthinEditField.Editable = 'off';
            app.OutputsLabel = uilabel(app.UIFigure);
            app.OutputsLabel.Text = 'Outputs';
            app.EnterSurfaceAreaToBeginLabel = uilabel(app.UIFigure);
            app.EnterSurfaceAreaToBeginLabel.Text = 'Enter Surface Area To Begin';
            app.IesuAgapito2216065ProfClaydonLabel = uilabel(app.UIFigure);
            app.IesuAgapito2216065ProfClaydonLabel.Text = {'Iesu Agapito'; '2216065'; 'Prof. Claydon'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Section2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end